classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        CSVTab                         matlab.ui.container.Tab
        ExportTableButton              matlab.ui.control.Button
        UITable_2                      matlab.ui.control.Table
        ExportGraphButton              matlab.ui.control.Button
        CalculateRecoveryRateButton    matlab.ui.control.Button
        CalculateRecoveryButton        matlab.ui.control.Button
        GenerateFRAPCurveButton        matlab.ui.control.Button
        DataAnalysisLabel              matlab.ui.control.Label
        EnterofprebleachframesEditField  matlab.ui.control.NumericEditField
        EnterofprebleachframesEditFieldLabel  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        InputFileButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CZITab                         matlab.ui.container.Tab
        ExportGraphButton_2            matlab.ui.control.Button
        ExportTableButton_2            matlab.ui.control.Button
        UITable_3                      matlab.ui.control.Table
        CalculateRecoveryRateButton_2  matlab.ui.control.Button
        CalculateRecoveryButton_2      matlab.ui.control.Button
        GenerateFRAPCurveButton_2      matlab.ui.control.Button
        DataAnalysisLabel_2            matlab.ui.control.Label
        EnterofprebleachframesEditField_2  matlab.ui.control.NumericEditField
        EnterofprebleachframesEditField_2Label  matlab.ui.control.Label
        SelectROIButton                matlab.ui.control.Button
        FrameSlider                    matlab.ui.control.Slider
        FrameSliderLabel               matlab.ui.control.Label
        InputFileButton_2              matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data % Description
        prebleachFrames % The amount of frames before bleaching occurs
        ROI
        post_bleach  % the Mean Fluorescence Intensity frame after bleaching
        post_bleach1
        final_fluorescence % The final Mean Fluorescence Intensity
        final_fluorescence1 
        pre_bleach_ave % the average of the Mean Fluorescence Intensities in the pre-bleach frames
        pre_bleach_ave1
        percentRecovery % the calculated percent recovery of the fluorescence
        percentRecovery1 % that is for the czi file
        fluo_change % the average change of fluorescence at each data point
        fluo_change1 
        linear_change  % the negative log of the fluorescence change to linearize the data
        linear_change1
        recovery_time % read from the excel file and then modified
        recoveryRate % best line of fit
        recoveryRate1 
        R_2 % goodness of fit
        R_22
        no_planes % number of planes/frames in the CZI file
        no_frame % number of frame on slider
        pixelData
        fluo_pixel % extracted "fluorescence" data from CZI image
        ROI_selected % checks if user has pushed the select ROI button
        prebleachCZI %prebleach frames for CZI


    end

    methods (Access = private)

        function [out] = percent_recovery(app)

            if isempty(app.prebleachCZI)
                preFrames = app.prebleachFrames;
                for i=2: width(app.data)
                    app.final_fluorescence(:,i-1) = app.data(end,i); % final fluorescence intensity
                    % error handling: warn user to rember to enter number of pre
                    % bleach frames that is above zero as well.
                    app.post_bleach = app.data(preFrames+1,2:end);
                    app.pre_bleach_ave(:,i-1)= mean(app.data(1:preFrames,i));
                    app.percentRecovery(:,i-1)= 100*((app.final_fluorescence(:,i-1)-app.post_bleach(:,i-1))./(app.pre_bleach_ave(:,i-1)- app.post_bleach(:,i-1)));
                    out = app.percentRecovery;
                end
            else
                preFrames = app.prebleachCZI;
                app.final_fluorescence1 = app.fluo_pixel(end); % final fluorescence intensity
                app.post_bleach1 = app.fluo_pixel(preFrames+1,1);
                app.pre_bleach_ave1= mean(app.fluo_pixel(1:preFrames,1));
                app.percentRecovery1= 100*((app.final_fluorescence1-app.post_bleach1)./(app.pre_bleach_ave1- app.post_bleach1));
            end


        end


        function recovery_rate(app)
            if isempty(app.prebleachCZI)
                preFrames = app.prebleachFrames;

                for i=2: width(app.data)


                    height = app.data(47:end,1);
                    k = size(height);
                    k = k(1);
                    app.fluo_change(:,i-1) = (app.data((preFrames+1):end,i)-app.post_bleach(:,i-1))/(app.final_fluorescence(:,i-1)-app.post_bleach(:,i-1));
                    app.linear_change(:,i-1) = -log(1-app.fluo_change(:,i-1));
                    app.linear_change = real(app.linear_change);


                    app.recovery_time = (app.data((preFrames+1):end,1));
                    x = app.recovery_time(1:end-1);
                    y(1:length(app.linear_change)-1,i-1) = app.linear_change(1:end-1,i-1); % take out the inf

                    linearCoefficients(1:2,i-1) = polyfit(x,y(:,i-1),1);

                    yfit(1:length(x),i-1) = polyval(linearCoefficients(:,i-1), x);
                    SStot(:,i-1) = sum((real(y(:,i-1))-mean(y(:,i-1))).^2);                    % Total Sum-Of-Squares
                    SSres(:,i-1) = sum((real(y(:,i-1))-real(yfit(:,i-1))).^2);                       % Residual Sum-Of-Squares
                    Rsq = 1-SSres./SStot;
                    app.recoveryRate = linearCoefficients(1,:);
                    app.R_2 = Rsq;


                end
            else
                preFrames = app.prebleachCZI;
                app.fluo_change1 = (app.fluo_pixel((preFrames+1):end,1)-app.post_bleach1)/(app.final_fluorescence1-app.post_bleach1);
                app.linear_change1 = -log(1-app.fluo_change1);
                app.linear_change1 = real(app.linear_change1);


                timeCZI = 0:1:(length(app.pixelData)-(preFrames+1));
                x2 = timeCZI(1:end-1);
                y2(1:length(app.linear_change1)-1,1) = app.linear_change1(1:end-1,1); % take out the inf1

                linearCoefficients2(1:2,1) = polyfit(x2,y2,1);

                yfit2(1:length(x2),1) = polyval(linearCoefficients2(:,1), x2);
                SStot2 = sum((real(y2(:,1))-mean(y2(:,1))).^2);                    % Total Sum-Of-Squares
                SSres2 = sum((real(y2(:,1))-real(yfit2(:,1))).^2);                       % Residual Sum-Of-Squares
                Rsq2 = 1-SSres2./SStot2;
                app.recoveryRate1 = linearCoefficients2(1,:);
                app.R_22 = Rsq2;


            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputFileButton
        function InputFileButtonPushed(app, event)
            
            % allow the user to select the desired file
            [FileName,PathName] = uigetfile('.csv');
            % prevents the figure from going back to the window
            %drawnow;
            figure(app.UIFigure)
            try
                assert(FileName>0);
            catch ME
                if isequal(FileName,0)
                    msgbox('User did not select file');

                else
                    file = fullfile(PathName, FileName);
                    % read the csv file
                    C = readtable(file);
                    app.UITable.Data = C; % apply the data to the UI table
                    app.UITable.ColumnName = C.Properties.VariableNames; % apply the names to the table
                    noROIs = width(C)-1;
                    app.data = table2array(C);
                    % initialize the table according to the number of ROIs
                    app.UITable_2.Data(noROIs,3) = zeros; % initialize the table
                    % Assign the name of each column
                    app.UITable_2.ColumnName ={'Percent Recovery','Recovery Rate','R^{2}'};

                    % this loops creates strings for as many ROIs as the table has
                    for i=1:noROIs
                        varName = strcat( 'ROI',num2str(i) );
                        ROIname{i,:} = varName;
                    end
                    % Assign the name of each row
                    app.UITable_2.RowName = ROIname;
                end
            end

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;

        end

        % Callback function
        function InsertnoofROIsButtonPushed(app, event)

        end

        % Value changed function: EnterofprebleachframesEditField
        function EnterofprebleachframesEditFieldValueChanged(app, event)
            app.prebleachFrames = app.EnterofprebleachframesEditField.Value;

        end

        % Button pushed function: GenerateFRAPCurveButton
        function GenerateFRAPCurveButtonPushed(app, event)
            plot(app.UIAxes,app.data(:,1),app.data(:,2))
        end

        % Button pushed function: CalculateRecoveryButton
        function CalculateRecoveryButtonPushed(app, event)
            preFrames = app.prebleachFrames;

            try
                assert(preFrames>0);

            catch ME
                if  preFrames<0
                    preFrames = abs(preFrames);
                    app.prebleachFrames = preFrames;
                    msgbox('Entered a negative value but took it as positive')
                    %percent_recovery(app);
                    %app.UITable_2.Data(:,1) = app.percentRecovery;

                else
                    errordlg('Must enter number of pre-bleach frames')
                end

            end
            if app.prebleachFrames>0
                percent_recovery(app);
                app.UITable_2.Data(:,1) = app.percentRecovery;
            end
        end

        % Button pushed function: CalculateRecoveryRateButton
        function CalculateRecoveryRateButtonPushed(app, event)
            preFrames = app.prebleachFrames;

            try
                assert(preFrames>0);
            catch ME
                if  preFrames<0
                    preFrames = abs(preFrames);
                    app.prebleachFrames = preFrames;
                    msgbox('Entered a negative value but took it as positive')


                else
                    errordlg('Must enter number of pre-bleach frames')
                end

            end
            if app.prebleachFrames>0
                recovery_rate(app);
                app.UITable_2.Data(:,2) = app.recoveryRate;
                app.UITable_2.Data(:,3) = app.R_2;
            end

        end

        % Button pushed function: ExportTableButton
        function ExportTableButtonPushed(app, event)

            name = inputdlg('Enter file name:');
            name = char(name);
            figure(app.UIFigure)
            if name~=0

                dname = uigetdir();
                filename = [name '.csv'];
                fullFileName = fullfile(dname, filename);
                t = array2table(app.UITable_2.Data); % convert the data from array to table.
                t.Properties.VariableNames = app.UITable_2.ColumnName; % save the column names
                t.Properties.RowNames = app.UITable_2.RowName; % save the row names
                writetable(t, fullFileName,'WriteRowNames',true); % must add to write the row names
            else
                x=20; % just any random thing

            end

        end

        % Button pushed function: InputFileButton_2
        function InputFileButton_2Pushed(app, event)
            %path='C:\Users\layla\OneDrive\Desktop\BIOE 4064\InitialCodeDesgin';
            %filename='\S3_20mMG_Min6.czi';
            %file = strcat(path,filename);


            cziFile = bfopen();

            try
                assert(cziFile>0);
            catch ME
                if isequal(cziFile,0)
                    msgbox('User did not select file');

                else
                    % find the number of series in the data
                    size = height(cziFile);
                    no_series = size;
                    % find the number of planes
                    planes = cziFile{1,1};
                    app.no_planes = length(planes);
                    % The following loop extracts the pixel intensity for each data series
                    % data{no_series,1}{plane#,1}
                    for i=1:no_series
                        for k=1:app.no_planes
                            app.pixelData{i,k} = cziFile{i,1}{k,1};
                        end
                    end
                    % find the colormap from the bioformat
                    series1_colorMap1 = cziFile{1, 3}{1, 1};

                    if isempty(series1_colorMap1)
                        colormap(app.UIAxes2,gray); % keep grayscale
                    else
                        colormap(app.UIAxes2,series1_colorMap1);
                    end

                    imshow(app.pixelData{1},'Parent',app.UIAxes2);

                    figure(app.UIFigure)
                end
            end

            % initialize the table according to the number of ROIs
            app.UITable_3.Data(:,3) = zeros; % initialize the table
            % Assign the name of each column
            app.UITable_3.ColumnName ={'Percent Recovery','Recovery Rate','R^{2}'};
            % Assign the name of row
            app.UITable_3.RowName = {'selected ROI'};
        end

        % Button pushed function: ExportGraphButton
        function ExportGraphButtonPushed(app, event)
            name = inputdlg('Enter file name:');
            name = char(name);
            figure(app.UIFigure)
            if name~=0

                dname = uigetdir();
                filename = name;
                fullFileName = fullfile(dname, filename);
                % Create a temporary figure with axes.
                fig = figure;
                % off visibility for user not to see
                fig.Visible = 'off';
                % create axes for fig
                figAxes = axes(fig);
                % Copy all UIAxes children, take over axes limits and aspect ratio.
                allChildren = app.UIAxes.XAxis.Parent.Children;
                copyobj(allChildren, figAxes)
                figAxes.XLim = app.UIAxes.XLim;
                figAxes.YLim = app.UIAxes.YLim;
                % copy title and labels of UIAxes
                figAxes.Title = app.UIAxes.Title;
                figAxes.XLabel = app.UIAxes.XLabel;
                figAxes.YLabel = app.UIAxes.YLabel;
                figAxes.DataAspectRatio = app.UIAxes.DataAspectRatio;
                % Save as png and fig files.
                saveas(fig, fullFileName, 'png');
                figure(app.UIFigure)
            else
                x=20; % just any random thing

            end
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)

            app.FrameSlider.Limits = [1 app.no_planes];
            app.no_frame = round(app.FrameSlider.Value);
            imshow(app.pixelData{app.no_frame},'Parent',app.UIAxes2);
            figure(app.UIFigure)


        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            changingValue = event.Value;

        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            % allow user to select desired area
            ROI_select = drawrectangle(app.UIAxes2);
            app.ROI_selected = 1;
            % extract the position values for selected area
            pos_rect = ROI_select.Position();
            pos_rect = round(pos_rect);
            % save 512X512#of fram into a matrix
            for i=1:length(app.pixelData)
                img1(:,:,i)=app.pixelData{i};
            end

            for i=1:length(app.pixelData)
                img = img1(:,:,i); % loop for each frame
                img_cropped = img(pos_rect(2) + (0:pos_rect(4)), pos_rect(1) + (0:pos_rect(3))); % extract pixel data from selected area
                col_mean = mean(img_cropped,1); % take the mean of each column
                frameMean = mean(col_mean); % take the mean of the average of total columns
                app.fluo_pixel(i,1) = frameMean; % save into vector representing the fluorescnece
            end

        end

        % Button pushed function: GenerateFRAPCurveButton_2
        function GenerateFRAPCurveButton_2Pushed(app, event)

            if app.ROI_selected == 1 % checks if user selected ROI
                % creat time vector
                timeCZI = 1:1:length(app.pixelData);
                % plot
                plot(app.UIAxes_3,timeCZI,app.fluo_pixel)
                drawnow
            else
                errordlg('Must Select the ROI') % notify the user!
            end
        end

        % Value changed function: EnterofprebleachframesEditField_2
        function EnterofprebleachframesEditField_2ValueChanged(app, event)
            app.prebleachCZI = app.EnterofprebleachframesEditField_2.Value;
            try
                assert(app.prebleachCZI>0);

            catch ME
                if  app.prebleachCZI<0
                    app.prebleachCZI = abs(app.prebleachCZI);
                    app.prebleachCZI = app.prebleachCZI;
                    msgbox('Entered a negative value but took it as positive')
                elseif app.prebleachCZI == 0
                    errordlg('Must enter number of pre-bleach frames')
                end

            end


        end

        % Button pushed function: CalculateRecoveryButton_2
        function CalculateRecoveryButton_2Pushed(app, event)
            if app.ROI_selected == 1
                EnterofprebleachframesEditField_2ValueChanged(app, event)
                preFrames = app.prebleachCZI;

                % add the data to table
                if app.prebleachCZI>0
                    percent_recovery(app);
                    app.UITable_3.Data(:,1) = app.percentRecovery1;
                end
            else
                errordlg('Must Select ROI for Analysis')
            end
        end

        % Button pushed function: CalculateRecoveryRateButton_2
        function CalculateRecoveryRateButton_2Pushed(app, event)

            EnterofprebleachframesEditField_2ValueChanged(app, event)
            preFrames = app.prebleachCZI;
            if app.prebleachCZI>0
                recovery_rate(app);
                app.UITable_3.Data(1,2) = app.recoveryRate1;
                app.UITable_3.Data(1,3) = app.R_22;
            end

        end

        % Button pushed function: ExportGraphButton_2
        function ExportGraphButton_2Pushed(app, event)
            name = inputdlg('Enter file name:');
            name = char(name);
            figure(app.UIFigure)
            if name~=0

                dname = uigetdir();
                filename = name;
                fullFileName = fullfile(dname, filename);
                % Create a temporary figure with axes.
                fig = figure;
                % off visibility for user not to see
                fig.Visible = 'off';
                % create axes for fig
                figAxes = axes(fig);
                % Copy all UIAxes children, take over axes limits and aspect ratio.
                allChildren = app.UIAxes_3.XAxis.Parent.Children;
                copyobj(allChildren, figAxes)
                figAxes.XLim = app.UIAxes_3.XLim;
                figAxes.YLim = app.UIAxes_3.YLim;
                % copy title and labels of UIAxes
                figAxes.Title = app.UIAxes_3.Title;
                figAxes.XLabel = app.UIAxes_3.XLabel;
                figAxes.YLabel = app.UIAxes_3.YLabel;
                figAxes.DataAspectRatio = app.UIAxes_3.DataAspectRatio;
                % Save as png and fig files.
                saveas(fig, fullFileName, 'png');
                figure(app.UIFigure)
            else
                x=20; % just any random thing

            end
        end

        % Button pushed function: ExportTableButton_2
        function ExportTableButton_2Pushed(app, event)
            name = inputdlg('Enter file name:');
            name = char(name);
            figure(app.UIFigure)
            if name~=0

                dname = uigetdir();
                filename = [name '.csv'];
                fullFileName = fullfile(dname, filename);
                t = array2table(app.UITable_3.Data); % convert the data from array to table.
                t.Properties.VariableNames = app.UITable_3.ColumnName; % save the column names
                t.Properties.RowNames = app.UITable_3.RowName; % save the row names
                writetable(t, fullFileName,'WriteRowNames',true); % must add to write the row names
            else
                x=20; % just any random thing

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FRAP Curve')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Fluorescence Intensity')
            zlabel(app.UIAxes_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CSVTab = uitab(app.TabGroup);
            app.CSVTab.Title = 'CSV';
            app.UIAxes = uiaxes(app.CSVTab);
            title(app.UIAxes, 'FRAP Curve')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Fluorescence Intensity')
            zlabel(app.UIAxes, 'Z')
            app.InputFileButton = uibutton(app.CSVTab, 'push');
            app.InputFileButton.ButtonPushedFcn = createCallbackFcn(app, @InputFileButtonPushed, true);
            app.InputFileButton.Text = 'Input File';
            app.UITable = uitable(app.CSVTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.EnterofprebleachframesEditFieldLabel = uilabel(app.CSVTab);
            app.EnterofprebleachframesEditFieldLabel.Text = 'Enter # of pre-bleach frames';
            app.EnterofprebleachframesEditField = uieditfield(app.CSVTab, 'numeric');
            app.EnterofprebleachframesEditField.ValueChangedFcn = createCallbackFcn(app, @EnterofprebleachframesEditFieldValueChanged, true);
            app.DataAnalysisLabel = uilabel(app.CSVTab);
            app.DataAnalysisLabel.Text = 'Data Analysis';
            app.GenerateFRAPCurveButton = uibutton(app.CSVTab, 'push');
            app.GenerateFRAPCurveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFRAPCurveButtonPushed, true);
            app.GenerateFRAPCurveButton.Text = 'Generate FRAP Curve';
            app.CalculateRecoveryButton = uibutton(app.CSVTab, 'push');
            app.CalculateRecoveryButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRecoveryButtonPushed, true);
            app.CalculateRecoveryButton.Text = 'Calculate % Recovery';
            app.CalculateRecoveryRateButton = uibutton(app.CSVTab, 'push');
            app.CalculateRecoveryRateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateRecoveryRateButtonPushed, true);
            app.CalculateRecoveryRateButton.Text = 'Calculate Recovery Rate';
            app.ExportGraphButton = uibutton(app.CSVTab, 'push');
            app.ExportGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGraphButtonPushed, true);
            app.ExportGraphButton.Text = 'Export Graph';
            app.UITable_2 = uitable(app.CSVTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.UITable_2.RowName = {};
            app.ExportTableButton = uibutton(app.CSVTab, 'push');
            app.ExportTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButtonPushed, true);
            app.ExportTableButton.Text = 'Export Table';
            app.CZITab = uitab(app.TabGroup);
            app.CZITab.Title = 'CZI';
            app.UIAxes2 = uiaxes(app.CZITab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes_3 = uiaxes(app.CZITab);
            title(app.UIAxes_3, 'FRAP Curve')
            xlabel(app.UIAxes_3, 'Frames')
            ylabel(app.UIAxes_3, 'Fluorescence Intensity')
            zlabel(app.UIAxes_3, 'Z')
            app.InputFileButton_2 = uibutton(app.CZITab, 'push');
            app.InputFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputFileButton_2Pushed, true);
            app.InputFileButton_2.Text = 'Input File';
            app.FrameSliderLabel = uilabel(app.CZITab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.CZITab);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.SelectROIButton = uibutton(app.CZITab, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.EnterofprebleachframesEditField_2Label = uilabel(app.CZITab);
            app.EnterofprebleachframesEditField_2Label.Text = 'Enter # of pre-bleach frames';
            app.EnterofprebleachframesEditField_2 = uieditfield(app.CZITab, 'numeric');
            app.EnterofprebleachframesEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterofprebleachframesEditField_2ValueChanged, true);
            app.DataAnalysisLabel_2 = uilabel(app.CZITab);
            app.DataAnalysisLabel_2.Text = 'Data Analysis';
            app.GenerateFRAPCurveButton_2 = uibutton(app.CZITab, 'push');
            app.GenerateFRAPCurveButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateFRAPCurveButton_2Pushed, true);
            app.GenerateFRAPCurveButton_2.Text = 'Generate FRAP Curve';
            app.CalculateRecoveryButton_2 = uibutton(app.CZITab, 'push');
            app.CalculateRecoveryButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateRecoveryButton_2Pushed, true);
            app.CalculateRecoveryButton_2.Text = 'Calculate % Recovery';
            app.CalculateRecoveryRateButton_2 = uibutton(app.CZITab, 'push');
            app.CalculateRecoveryRateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateRecoveryRateButton_2Pushed, true);
            app.CalculateRecoveryRateButton_2.Text = 'Calculate Recovery Rate';
            app.UITable_3 = uitable(app.CZITab);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; ''; ''};
            app.UITable_3.RowName = {};
            app.ExportTableButton_2 = uibutton(app.CZITab, 'push');
            app.ExportTableButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportTableButton_2Pushed, true);
            app.ExportTableButton_2.Text = 'Export Table';
            app.ExportGraphButton_2 = uibutton(app.CZITab, 'push');
            app.ExportGraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportGraphButton_2Pushed, true);
            app.ExportGraphButton_2.Text = 'Export Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end